classdef appMV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        OriginalImageLabel  matlab.ui.control.Label
        Image               matlab.ui.control.Image
        SearchButton        matlab.ui.control.Button
        FileNameEditField   matlab.ui.control.EditField
        FileNameLabel       matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
        function Img = readFile(~, file)
            ImgID = fopen(file, 'r');
            formatSpec = '%s';
            Img1 = fscanf(ImgID, formatSpec, [64,64]);
            Img = zeros(64);
            for i = 1:1:64
                for j = 1:1:64
                    num = abs(Img1(j,i));
                    if num < 58
                        num = num - 48;
                    else
                        num = num - 55;
                    end
                    Img(i,j) = num; % transpose
                    
                end
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Image, SearchButton
        function SearchButtonPushed(app, event)

            [fileID, path] = uigetfile('*.txt');
            app.FileNameEditField.Value = fileID;
            % fileID = str(fileID);
            Img = app.readFile(fileID);
            app.Image.ImageSource = repmat(Img, 1, 1, 3);
%             % a = imshow(Img);
%             imagesc(app.UIAxes, Img)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.SearchButton = uibutton(app.UIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.OriginalImageLabel = uilabel(app.Panel);
            app.OriginalImageLabel.Text = 'Original Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end