classdef ME5405_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ThresholdingLevelEditField   matlab.ui.control.NumericEditField
        ThresholdingLevelEditFieldLabel  matlab.ui.control.Label
        NoofColumnsEditField         matlab.ui.control.NumericEditField
        NoofColumnsEditField_2Label  matlab.ui.control.Label
        NoofRowsEditField            matlab.ui.control.NumericEditField
        NoofRowsEditField_2Label     matlab.ui.control.Label
        BackgroundColorButtonGroup   matlab.ui.container.ButtonGroup
        WhiteButton                  matlab.ui.control.ToggleButton
        BlackButton                  matlab.ui.control.ToggleButton
        FileBrowserButton            matlab.ui.control.Button
        ProcessButton                matlab.ui.control.Button
        OriginalImageLabel           matlab.ui.control.Label
        SelectOutputButtonGroup      matlab.ui.container.ButtonGroup
        Button_14                    matlab.ui.control.ToggleButton
        Button_13                    matlab.ui.control.ToggleButton
        Button_12                    matlab.ui.control.ToggleButton
        Button_11                    matlab.ui.control.ToggleButton
        Button_10                    matlab.ui.control.ToggleButton
        Button_9                     matlab.ui.control.ToggleButton
        Button_8                     matlab.ui.control.ToggleButton
        Button_7                     matlab.ui.control.ToggleButton
        Button_6                     matlab.ui.control.ToggleButton
        Button_5                     matlab.ui.control.ToggleButton
        Button_4                     matlab.ui.control.ToggleButton
        Button_3                     matlab.ui.control.ToggleButton
        FileNameEditField            matlab.ui.control.EditField
        FileNameEditFieldLabel       matlab.ui.control.Label
        OutputImage                  matlab.ui.control.Image
        OriginalImage                matlab.ui.control.Image
    end

    
    properties (Access = private)
        cols = 0; % Description
        rows = 0; % Description
        threshold % Description
        backgroundcolor % Description
        MatGrayscale % Description
        MatBinary % Description
        MatSkel % Description
        MatOutline % Description
        MatSegLabeled % Description
        MatArranged % Description
        MatRotated % Description
    end
    
    methods (Access = private)
        
        function MatGrayscale = readFile(~, filename)
            
            
            % Read the intended file with fileread, then read each line,
            % splitting by newline and carriage return characters. Then,
            % vertically concatenate it to recreate the image
            MatOriginal = regexp(fileread(filename), '\r?\n', 'split');
            MatOriginal = vertcat(MatOriginal{:});
            
            %% Task 1: Display the original image on the screen
            % Make a new matrix called MatGrayscale that is the grayscaled version of
            % MatOriginal.
            MatGrayscale = MatOriginal;
            
            % Based on ASCII, we first ensure that any strange character values that
            % are not 0-9 or A-V are changed to their corresponding extreme values of 0
            % or 31. Values inbetween 9 and A are set to 10.
            MatGrayscale(MatGrayscale >= '!' & MatGrayscale <= '/') = 0;
            MatGrayscale(MatGrayscale >= 'W' & MatGrayscale <= '~') = 31;
            MatGrayscale(MatGrayscale >= ':' & MatGrayscale <= '@') = 10;
            
            % Based on the ASCII table, we must first convert all numbers 0-9 in the
            % image which are stored as characters to their corresponding numbers. This
            % is done by minusing 48 (as 0 equates to 48 in the ASCII table, 1 to 50,
            % and so on).
            MatGrayscale(MatGrayscale >= '0' & MatGrayscale <= '9') = MatGrayscale(MatGrayscale >= '0' & MatGrayscale <= '9') - 48;
            
            % Only letters from A to V are left, so we can convert everything that is a
            % letter by minusing 55 (as A equates to 65 in the ASCII table). 
            MatGrayscale(isletter(MatGrayscale))= MatGrayscale(isletter(MatGrayscale)) - 55;
            
            % Convert to an unsinged integer matrix
            MatGrayscale = uint8(MatGrayscale);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FileBrowserButton
        function FileBrowserButtonPushed(app, event)
            [fileID, ~] = uigetfile('*.txt');
            app.FileNameEditField.Value = fileID;
            app.MatGrayscale = readFile(app, fileID);
            [app.rows,app.cols] = size(app.MatGrayscale);
            app.OriginalImage.ImageSource = repmat(mat2gray(app.MatGrayscale,[0,31]), 1, 1, 3);
            app.NoofColumnsEditField.Value = app.cols;
            app.NoofRowsEditField.Value = app.rows;
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            app.threshold = app.ThresholdingLevelEditField.Value;
            if (app.BlackButton.Value == 1)
                app.backgroundcolor = 'B';
            else
                app.backgroundcolor = 'W';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImage = uiimage(app.UIFigure);
            app.OutputImage = uiimage(app.UIFigure);
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Editable = 'off';
            app.SelectOutputButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectOutputButtonGroup.Title = 'Select Output';
            app.Button_3 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_3.Text = 'Button';
            app.Button_3.Value = true;
            app.Button_4 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_4.Text = 'Button';
            app.Button_5 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_5.Text = 'Button';
            app.Button_6 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_6.Text = 'Button';
            app.Button_7 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_7.Text = 'Button';
            app.Button_8 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_8.Text = 'Button';
            app.Button_9 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_9.Text = 'Button';
            app.Button_10 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_10.Text = 'Button';
            app.Button_11 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_11.Text = 'Button';
            app.Button_12 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_12.Text = 'Button';
            app.Button_13 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_13.Text = 'Button';
            app.Button_14 = uitogglebutton(app.SelectOutputButtonGroup);
            app.Button_14.Text = 'Button';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'PROCESS';
            app.FileBrowserButton = uibutton(app.UIFigure, 'push');
            app.FileBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @FileBrowserButtonPushed, true);
            app.FileBrowserButton.Text = 'File Browser';
            app.BackgroundColorButtonGroup = uibuttongroup(app.UIFigure);
            app.BackgroundColorButtonGroup.Title = 'Background Color';
            app.BlackButton = uitogglebutton(app.BackgroundColorButtonGroup);
            app.BlackButton.Text = 'Black';
            app.BlackButton.Value = true;
            app.WhiteButton = uitogglebutton(app.BackgroundColorButtonGroup);
            app.WhiteButton.Text = 'White';
            app.NoofRowsEditField_2Label = uilabel(app.UIFigure);
            app.NoofRowsEditField_2Label.Text = 'No. of Rows';
            app.NoofRowsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofColumnsEditField_2Label = uilabel(app.UIFigure);
            app.NoofColumnsEditField_2Label.Text = 'No. of Columns';
            app.NoofColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdingLevelEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdingLevelEditFieldLabel.Text = 'Thresholding Level';
            app.ThresholdingLevelEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ME5405_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end